classdef monkeyResponse_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UITable                     matlab.ui.control.Table
        DesignBehaviorSummaryLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        Metadata
        Parameters
        Counters
        Events
        Behaviors
        Conditions
        Design
        Device
        Dynamo
    end

    methods (Access = public)
    
        function app = set_properties(app,Metadata,Parameters,Counters,Events,...
                                Behaviors,Conditions,Design,Device,Dynamo)
            app.Metadata = Metadata;
            app.Parameters = Parameters;            
            app.Counters = Counters;        
            app.Events = Events;   
            app.Behaviors = Behaviors;   
            app.Conditions = Conditions;
            app.Design = Design;
            app.Device = Device;
            app.Dynamo = Dynamo;
        end


        function results = func3(app)
            
        end
        
        function results = func2(app)
            
        end
        
    end


    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'n_trial'; 'n_condition'; 'n_participation'; 'n_correct'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.DesignBehaviorSummaryLabel = uilabel(app.UIFigure);
            app.DesignBehaviorSummaryLabel.Text = 'Design & Behavior Summary';
        end
    end

    methods (Access = public)

        % Construct app
        function app = monkeyResponse_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end