classdef monkeyResponse_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        monkeyResponseUIFigure         matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        CurrentBehavioralSummaryLabel  matlab.ui.control.Label
        taskEditFieldLabel             matlab.ui.control.Label
        taskEditField                  matlab.ui.control.EditField
        subjectEditFieldLabel          matlab.ui.control.Label
        subjectEditField               matlab.ui.control.EditField
        sessionEditFieldLabel          matlab.ui.control.Label
        sessionEditField               matlab.ui.control.EditField
        timeEditFieldLabel             matlab.ui.control.Label
        timeEditField                  matlab.ui.control.EditField
        experimenterEditFieldLabel     matlab.ui.control.Label
        experimenterEditField          matlab.ui.control.EditField
        durationEditFieldLabel         matlab.ui.control.Label
        durationEditField              matlab.ui.control.EditField
        PAUSEButton                    matlab.ui.control.StateButton
        STOPButton                     matlab.ui.control.StateButton
        UITable_2                      matlab.ui.control.Table
        CumulativeBehavioralSummaryLabel_2  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ParametersTab                  matlab.ui.container.Tab
        volume_unitEditFieldLabel      matlab.ui.control.Label
        volume_unitEditField           matlab.ui.control.NumericEditField
        DynamometerTab                 matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        PeriodLampLabel                matlab.ui.control.Label
        PeriodLamp                     matlab.ui.control.Lamp
    end


    properties (Access = public)
        Metadata
        Parameters
        Counters
        Events
        Behaviors
        Conditions
        Design
        Device
        Dynamo
    end

    methods (Access = public)
    
        function app = set_properties(app,Metadata,Parameters,Counters,Events,...
                                Behaviors,Conditions,Design,Device,Dynamo)
            app.Metadata = Metadata;
            app.taskEditField.Value = Metadata.taskName;
            app.subjectEditField.Value = Metadata.subName;
            app.sessionEditField.Value = num2str(Metadata.nSession);
            app.timeEditField.Value = Metadata.time;
            app.experimenterEditField.Value = Metadata.experimenterName;
            app.durationEditField.Value = Metadata.duration;
            
            if ~isempty(Parameters)
                app.Parameters = Parameters;   
            end
            
            app.Counters = Counters;        
            app = set_counter(app);
            
            app.Events = Events;   
            app.PAUSEButton.Value = app.Events.pauseTask;
            
            app.Behaviors = Behaviors;   
            
            app.Conditions = Conditions;
            
            app.Design = Design;

            if ~isempty(Device)
                app.Device = Device;
                app.volume_unitEditField.Value = app.Device.reward.volumeUnit;
            end
            
            app.Dynamo = Dynamo;
            app = display_dynamo(app);
            
        end
        
        function app = set_counter(app)
            if app.Counters.iTrial~=0
                app.UITable.Data = [app.Design.nRepetition(app.Counters.iTrial),...
                                    app.Behaviors.correctResponse(app.Counters.iTrial) ];
            end
            
            app.UITable_2.Data = [app.Counters.iTrial,...
                                app.Counters.iCondition,...
                                app.Counters.nParticipation,...
                                app.Counters.nCorrect,...
                                app.Counters.vReward ];
        end
        
        
        function app = display_dynamo(app)
            if ~isempty(app.Dynamo.forceSignal)
                n_window = 60*5;
                n_max = numel(app.Dynamo.time);
                n_window = min(n_window,n_max);
                index = [n_max-n_window+1:n_max];      
                f = app.Dynamo.forceSignal(:,index);
                t = app.Dynamo.time(index)- app.Dynamo.time(1)  ;
                hold(app.UIAxes,'off');
                plot(app.UIAxes,t,f(1,:),'b','LineWidth',1.5);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,t,f(2,:),'r','LineWidth',1.5);
                app.UIAxes.XLim = [min(t) max(t)];
            end
        end
        
        function [app,Metadata,Parameters,Counters,Events,...
                  Behaviors,Conditions,Design,Device,Dynamo] = update_properties(app)
                    Metadata = app.Metadata;
                    Parameters = app.Parameters;          
                    Counters = app.Counters;        
                    Events = app.Events;   
                    Behaviors = app.Behaviors;   
                    Conditions = app.Conditions;
                    Design = app.Design;
                    Device = app.Device;
                    Dynamo = app.Dynamo;            
        end
        
        function [] = set_periodLamp(app,state)
            switch state
                case 'iti'
                    app.PeriodLamp.Color='k';
                case 'condition'
                    app.PeriodLamp.Color='r';
                case 'response'
                    app.PeriodLamp.Color='g';
                case 'reward'
                    app.PeriodLamp.Color='b';
            end
        end
        
    end


    methods (Access = private)

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            value = app.STOPButton.Value;
            app.Events.stopTask = value;
        end

        % Value changed function: volume_unitEditField
        function volume_unitEditFieldValueChanged(app, event)
            value = app.volume_unitEditField.Value;
            app.Device.reward.volumeUnit = app.volume_unitEditField.Value;
            app.Device.reward.timeUnit = app.Device.reward.volumeUnit/app.Device.reward.time2volume;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.monkeyResponseUIFigure = uifigure;
            app.monkeyResponseUIFigure.Name = 'monkeyResponse';
            app.UITable = uitable(app.monkeyResponseUIFigure);
            app.UITable.ColumnName = {'i_repetition'; 'i_correct'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.CurrentBehavioralSummaryLabel = uilabel(app.monkeyResponseUIFigure);
            app.CurrentBehavioralSummaryLabel.Text = 'Current Behavioral Summary';
            app.taskEditFieldLabel = uilabel(app.monkeyResponseUIFigure);
            app.taskEditFieldLabel.Text = 'task';
            app.taskEditField = uieditfield(app.monkeyResponseUIFigure, 'text');
            app.subjectEditFieldLabel = uilabel(app.monkeyResponseUIFigure);
            app.subjectEditFieldLabel.Text = 'subject';
            app.subjectEditField = uieditfield(app.monkeyResponseUIFigure, 'text');
            app.sessionEditFieldLabel = uilabel(app.monkeyResponseUIFigure);
            app.sessionEditFieldLabel.Text = 'session';
            app.sessionEditField = uieditfield(app.monkeyResponseUIFigure, 'text');
            app.timeEditFieldLabel = uilabel(app.monkeyResponseUIFigure);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.monkeyResponseUIFigure, 'text');
            app.experimenterEditFieldLabel = uilabel(app.monkeyResponseUIFigure);
            app.experimenterEditFieldLabel.Text = 'experimenter';
            app.experimenterEditField = uieditfield(app.monkeyResponseUIFigure, 'text');
            app.durationEditFieldLabel = uilabel(app.monkeyResponseUIFigure);
            app.durationEditFieldLabel.Text = 'duration';
            app.durationEditField = uieditfield(app.monkeyResponseUIFigure, 'text');
            app.PAUSEButton = uibutton(app.monkeyResponseUIFigure, 'state');
            app.PAUSEButton.Text = 'PAUSE';
            app.STOPButton = uibutton(app.monkeyResponseUIFigure, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            app.UITable_2 = uitable(app.monkeyResponseUIFigure);
            app.UITable_2.ColumnName = {'n_trial'; 'n_condition'; 'n_participation'; 'n_correct'; 'v_reward'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = true;
            app.CumulativeBehavioralSummaryLabel_2 = uilabel(app.monkeyResponseUIFigure);
            app.CumulativeBehavioralSummaryLabel_2.Text = 'Cumulative Behavioral Summary';
            app.TabGroup = uitabgroup(app.monkeyResponseUIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.volume_unitEditFieldLabel = uilabel(app.ParametersTab);
            app.volume_unitEditFieldLabel.Text = 'volume_unit';
            app.volume_unitEditField = uieditfield(app.ParametersTab, 'numeric');
            app.volume_unitEditField.ValueChangedFcn = createCallbackFcn(app, @volume_unitEditFieldValueChanged, true);
            app.volume_unitEditField.Limits = [0.3 5];
            app.volume_unitEditField.Value = 1;
            app.DynamometerTab = uitab(app.TabGroup);
            app.DynamometerTab.Title = 'Dynamometer';
            app.UIAxes = uiaxes(app.DynamometerTab);
            title(app.UIAxes, 'Dynamometer')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'force signal')
            app.UIAxes.YLim = [0 1000];
            app.UIAxes.YLimMode = 'manual';
            app.PeriodLampLabel = uilabel(app.monkeyResponseUIFigure);
            app.PeriodLampLabel.Text = 'Period';
            app.PeriodLamp = uilamp(app.monkeyResponseUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = monkeyResponse_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.monkeyResponseUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.monkeyResponseUIFigure)
        end
    end
end